classdef app4_UI_Prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ExportasPDFMenu                 matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        CutMenu                         matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        PasteMenu                       matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        RunOptimizerMenu                matlab.ui.container.Menu
        RunMotionPlannerMenu            matlab.ui.container.Menu
        TestsMenu                       matlab.ui.container.Menu
        RunCase1Menu                    matlab.ui.container.Menu
        RunCase2Menu                    matlab.ui.container.Menu
        RunCase3Menu                    matlab.ui.container.Menu
        RunCase4Menu                    matlab.ui.container.Menu
        RunCase5Menu                    matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        Pointer                         matlab.ui.container.toolbar.ToggleTool
        Target                          matlab.ui.container.toolbar.ToggleTool
        Obstacle                        matlab.ui.container.toolbar.ToggleTool
        Eraser                          matlab.ui.container.toolbar.ToggleTool
        Run                             matlab.ui.container.toolbar.PushTool
        Pauser                          matlab.ui.container.toolbar.PushTool
        Continuar                       matlab.ui.container.toolbar.PushTool
        Stop                            matlab.ui.container.toolbar.PushTool
        PushTool4                       matlab.ui.container.toolbar.PushTool
        Animate                         matlab.ui.container.toolbar.PushTool
        RestoreView                     matlab.ui.container.toolbar.PushTool
        PushTool5                       matlab.ui.container.toolbar.PushTool
        PushTool6                       matlab.ui.container.toolbar.PushTool
        PushTool7                       matlab.ui.container.toolbar.PushTool
        FixedfirstangleCheckBox         matlab.ui.control.CheckBox
        FirstangleEditField             matlab.ui.control.NumericEditField
        FirstangleEditFieldLabel        matlab.ui.control.Label
        MutationmethodDropDownLabel     matlab.ui.control.Label
        MutationmethodDropDown          matlab.ui.control.DropDown
        SurvivalalphaEditField          matlab.ui.control.NumericEditField
        SurvivalalphaLabel              matlab.ui.control.Label
        SurvivalmethodDropDown          matlab.ui.control.DropDown
        SurvivalmethodDropDownLabel     matlab.ui.control.Label
        MutationprobabilitySpinner      matlab.ui.control.Spinner
        MutationprobabilitySpinnerLabel  matlab.ui.control.Label
        CrunchmethodDropDownLabel       matlab.ui.control.Label
        CrunchmethodDropDown            matlab.ui.control.DropDown
        GridLayout6                     matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        OptimizerTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        RobotParametersTab              matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        RobotRadiusEditFieldLabel       matlab.ui.control.Label
        RobotRadiusEditField            matlab.ui.control.NumericEditField
        MaterialLossCheckBox            matlab.ui.control.CheckBox
        TargetCollisionCheckBox         matlab.ui.control.CheckBox
        RobotModeDropDown               matlab.ui.control.DropDown
        RobotModeDropDownLabel          matlab.ui.control.Label
        MaxlengthEditFieldLabel         matlab.ui.control.Label
        MaxlengthEditField              matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel         matlab.ui.control.Label
        MinlengthEditField              matlab.ui.control.NumericEditField
        MaxangleEditField               matlab.ui.control.NumericEditField
        MaxangleEditFieldLabel          matlab.ui.control.Label
        MinangleEditField               matlab.ui.control.NumericEditField
        MinangleEditFieldLabel          matlab.ui.control.Label
        LinksEditField                  matlab.ui.control.NumericEditField
        LinksEditFieldLabel             matlab.ui.control.Label
        OptimizationAlgorithmTab        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        PenaltymethodDropDownLabel      matlab.ui.control.Label
        PenaltymethodDropDown           matlab.ui.control.DropDown
        CrossoverprobabilitySpinnerLabel  matlab.ui.control.Label
        CrossoverprobabilitySpinner     matlab.ui.control.Spinner
        CrossovermethodDropDownLabel    matlab.ui.control.Label
        CrossovermethodDropDown         matlab.ui.control.DropDown
        IndividualsEditFieldLabel       matlab.ui.control.Label
        IndividualsEditField            matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel       matlab.ui.control.Label
        GenerationsEditField            matlab.ui.control.NumericEditField
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        SelectionmethodDropDown         matlab.ui.control.DropDown
        SelectionmethodDropDownLabel    matlab.ui.control.Label
        ObstacleavoidanceCheckBox       matlab.ui.control.CheckBox
        RankPartitioningAlgorithmTab    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        StepIKEditFieldLabel            matlab.ui.control.Label
        StepIKEditField                 matlab.ui.control.NumericEditField
        SteplengthEditField             matlab.ui.control.NumericEditField
        SteplengthEditFieldLabel        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PropertiesTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        TargetCollisionRadiusEditField  matlab.ui.control.NumericEditField
        TargetCollisionRadiusEditFieldLabel  matlab.ui.control.Label
        Rotator                         matlab.ui.control.Button
        AngledegEditField               matlab.ui.control.NumericEditField
        AngledegEditFieldLabel          matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        YcoordinateEditField            matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel       matlab.ui.control.Label
        XcoordinateEditField            matlab.ui.control.NumericEditField
        XcoordinateEditFieldLabel       matlab.ui.control.Label
        OptionsLabel                    matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Tree                            matlab.ui.container.Tree
        BaseNode                        matlab.ui.container.TreeNode
        TargetsNode                     matlab.ui.container.TreeNode
        ObstaclesNode                   matlab.ui.container.TreeNode
        ObjectsLabel                    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        OPTree                          matlab.ui.container.Tree
        Label                           matlab.ui.control.Label
        UIAxes1                         matlab.ui.control.UIAxes
        MotionPlannerTab                matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Tree2                           matlab.ui.container.Tree
        MPTree                          matlab.ui.container.Tree
        NextanimationNode               matlab.ui.container.TreeNode
        AnimationsLabel                 matlab.ui.control.Label
        GridLayout8                     matlab.ui.container.GridLayout
        SpeedperFrame1secto0secSlider   matlab.ui.control.Slider
        SpeedperFrame1secto0secSliderLabel  matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        TabGroup4                       matlab.ui.container.TabGroup
        StepSizeTab                     matlab.ui.container.Tab
        GridLayout3_3                   matlab.ui.container.GridLayout
        RetractingEditField             matlab.ui.control.NumericEditField
        RetractingEditFieldLabel        matlab.ui.control.Label
        GrowingEditField                matlab.ui.control.NumericEditField
        GrowingEditFieldLabel           matlab.ui.control.Label
        SteeringEditField               matlab.ui.control.NumericEditField
        SteeringEditField_3Label        matlab.ui.control.Label
        BaseRotateCheckBox              matlab.ui.control.CheckBox
        SimultaneousCheckBox            matlab.ui.control.CheckBox
        TypeofheuristicDropDown         matlab.ui.control.DropDown
        TypeofheuristicDropDownLabel    matlab.ui.control.Label
        TypeofalgorithmDropDown         matlab.ui.control.DropDown
        TypeofalgorithmDropDownLabel    matlab.ui.control.Label
        GrowingandretractingEditField   matlab.ui.control.NumericEditField
        GrowingandretractingEditFieldLabel  matlab.ui.control.Label
        SteeringEditField_2             matlab.ui.control.NumericEditField
        SteeringEditField_2Label        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        AnimateMenu                     matlab.ui.container.Menu
    end

    
    properties (Access = public)
        op % Description
        sp;
        gas
        
        minMax;
        UIAxes; % Description
        bbbcs
        algorithm
        stopRunItGeneric % Description
        latestTool = "arrow" % Description
        nChar % Description
        tabChar ;
        lastSelected;
        
        XLim = [-50 200];
            
        YLim = [-50 200]; % Description % Description
        loadedData % Description
        dragDropStart % Description
        
        dummyNode;
        
        mpPath; % Description
        clipboard; % Used for cut, copy, and paste
        animationsCount; % Only used for naming new nodes in MPTree
        MPRunning; % boolean, true: if an animation is being animated, false: otherwise
        previousX % previous X in MP
        previousY % previous Y in MP
        paused
        holdNode = -10;
        genHold = -10;
        scalerOP = 1;
        scalerMP = 1;
        selectedTab;
        figS = -10;
        gridd = -10;
        sld = -10;
         
    end
    
    properties (Access = private)
        running = 0;
        
    end
    
    
    methods (Access = public)
        %Another way to call private parse functions
        % function OPTreeSelection(app, event)
        %      OPTreeSelectionChanged(app,event);
        % end
        % 
        % function MPTreeSelection(app, event)
        %     MPTreeSelectionChanged(app, event);
        % end

        
        function SelectChangerOutCall(app, event)
            if numel(app.OPTree.Children)>0
                app.OPTree.SelectedNodes = app.OPTree.Children(end);
            end
            OPTreeSelectionChanged(app, event)
        end
        
        function PointerOnCall(app, event)
            PointerOn(app,event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add parent folder to path
            try
                cd icons
                cd ..
                addpath(genpath("."))
            catch
                addpath(genpath(".."))
            end
            
            app.dummyNode = [];
            app.nChar = newline; % Description
            app.tabChar = char(9);
            axis(app.UIAxes1,'equal');
            axis(app.UIAxes2,'equal');
            hold(app.UIAxes1,"on");
            hold(app.UIAxes2,"on");
            add_base_plot_and_node(app,0,0,0);
            disable_properties(app);
            axtoolbar(app.UIAxes1,"default","Visible","off");
            axtoolbar(app.UIAxes2,"default","Visible","off");

            app.UIAxes1.XLim = app.XLim;
            app.UIAxes1.YLim = app.YLim;
            app.UIAxes2.XLim = app.XLim;
            app.UIAxes2.YLim = app.YLim;
            loadDefaultOptions(app)

            % Draw Min Max Lengths
            drawMinMax(app);

            % Rename window
            app.UIFigure.Name = "Soft Robot Optimizer";

            % Select Base
            select_child(app,app.BaseNode.Children(1).NodeData.child);

            % initalize uiaxes
            app.UIAxes = app.UIAxes1;
            
            % Fill nextAnimation.NodeData
            app.MPTree.SelectedNodes = app.MPTree.Children(end);
            TypeofalgorithmDropDownValueChanged(app, 0);

        
        end

        % Button down function: UIAxes1
        function UIAxes1ButtonDown3(app, event)
            currentPoint = app.UIAxes1.CurrentPoint;
            x = currentPoint(1,1);
            y = currentPoint(1,2);
            
            if app.Pointer.State=="on"
                if ~isempty(app.lastSelected) && isprop(app.lastSelected, 'Selected') && ~isempty(app.lastSelected.Selected)
                    if app.lastSelected.Selected == "on"
                        ps_mouse_click(app,app.lastSelected);
                    end
                end
            else
                if numel(app.OPTree.Children)>1 && app.Eraser.State == "off"
                    result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                    if result  == "OK"
                        delete(app.OPTree.Children);
                        app.genHold = -10;
                        app.holdNode = -10;
                        buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                        for k = 1:length(buttons)
                            buttons(k).Enable = 'on';
                        end
                        app.Continuar.Enable = 'off';
                
                    else
                        return;
                    end
            
                end
                app.paused = false;
                if app.Obstacle.State == "on"
                    app.genHold = -10;
                    app.holdNode = -10;
                    create_polyshape(app,x,y,1,0,false,app.scalerOP, app.TargetCollisionRadiusEditField.Value(1));
                    init_segments(app);
                    % change cursor to arrow
                    PointerOn(app,event);
                
                elseif app.Target.State == "on"
                    app.genHold = -10;
                    app.holdNode = -10;
                    create_polyshape(app,x,y,1,0,true,app.scalerOP(1), 1);
                    init_segments(app);
                    % change cursor to arrow
                    PointerOn(app,event);
                end
            end
                      
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes ~= app.ObstaclesNode && selectedNodes ~= app.TargetsNode && selectedNodes~= app.BaseNode
                node_click(app);
            else
                if ishandle(app.figS)
                    figCloser(0, 0, app);
                end
                disable_properties(app);
                deselect_children(app);
            end
        end

        % Value changed function: AngledegEditField, LengthEditField, 
        % ...and 3 other components
        function xcoordinateEditFieldValueChanged(app, event)
            if numel(app.OPTree.Children)>1
                result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                if result  == "OK"
                    delete(app.OPTree.Children)
                else
                    return
                end
                
            end
            app.genHold = -10;
            app.holdNode = -10;
            app.Continuar.Enable = "off";
            app.paused = false;
            Apply(app);
            init_segments(app);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

        end

        % On callback: Pointer
        function PointerOn(app, event)
            % Pointer
            if app.Pointer.State == "off"
                app.Pointer.State = "on";
            end
            
            app.Target.State="off";
            app.Obstacle.State = "off";
            app.Eraser.State = "off";
            
            turn_children_hittest(app,"on")
            changePointerTo(app,"arrow");
            
            
        end

        % On callback: Obstacle
        function ObstacleOn(app, event)
            % Obstacle
            app.Pointer.State="off";
            app.Target.State = "off";
            app.Eraser.State = "off";
            % to be able to draw on them
            
            
            changePointerTo(app,"circle");
            turn_children_hittest(app,"off")
            
        end

        % Callback function: Eraser, Obstacle, Pointer, Target
        function PointerClicked(app, event)
            if event.Source.State == "off"
                event.Source.State = "on";
            end
            
        end

        % On callback: Target
        function TargetOn2(app, event)
            if app.Target.State == "off"
                app.Target.State = "on";
            end
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Eraser.State = 'off';
            % To be able to draw on them
            turn_children_hittest(app,"off")
            changePointerTo(app,'target');
            
        end

        % On callback: Eraser
        function EraserOn(app, event)
            disable_properties(app);
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Target.State = 'off';
            changePointerTo(app,'eraser');
            turn_children_hittest(app,"on")
            
        end

        % Callback function: RestoreView
        function RestoreViewClicked(app, event)
            app.UIAxes.XLim =  app.XLim;
            app.UIAxes.YLim =  app.YLim;
            drawMinMax(app);
            if app.selectedTab == app.OptimizerTab
                app.scalerOP = 1;
                stable_zoom(app);
                stable_zoom_Base(app);
            else
                app.scalerMP = 1;
                stable_zoom_MP(app);
            end
        end

        % Callback function: Run
        function RunClicked(app, event)

            app.paused = false;
            app.genHold = -10;
            app.holdNode = -10;
            if ishandle(app.figS)
                figCloser(0,0,app)
            end
            if app.running == 0
                app.running = 1;
                if numel(app.OPTree.Children)>1
                    result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");  
                    if result  == "OK"

                        app.paused = false;
                        app.genHold = -10;
                        app.holdNode = -10;
                    else
                        app.running = 0;
                        return;
                    end

                end
    
                if numel(app.TargetsNode.Children)>0
                    try
    
                        delete(app.OPTree.Children)
                        % disable all but stop
                        toggleUI(app, "off",[app.Stop, app.Pauser]);
                        
                        result = doesOverlap(app);
                        app.op.materialLoss = app.MaterialLossCheckBox.Value;
                        app.op.robotRad = app.RobotRadiusEditField.Value;
                        if ~result && ~(app.RobotModeDropDown.Value == "Carry Robot" && app.TargetCollisionCheckBox.Value && any(mod(size(app.op.targets), 2) == 1))
                            app.stopRunItGeneric = false;
                            sendRunItGeneric(app);
                            [~,~] = runIt_generic(app);
                            % configurations = KeepNonZeroRows(app,configurations);
                            % drawRobotConfigurations(app,configurations);
                        end
                        toggleUI(app, "on", [app.Stop, app.Pauser]);
                        if app.paused == true && app.genHold ~= app.GenerationsEditField.Value
                            buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                            for k = 1:length(buttons)
                                buttons(k).Enable = 'off';
                            end
                        else
                            app.Continuar.Enable = 'off';
                        end

            
                    catch er
                        uialert(app.UIFigure, er.message, 'Error');
    
                    end
                else
                    fig = app.UIFigure;
                    % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                    msg = 'No targets found.';
                    title = 'Soft Robot Optimizer';
                    uiconfirm(fig,msg,title, ...
                        'Options',{'OK'}, ...
                        'DefaultOption','OK','Icon','warning');
                end
                if ~isempty(app.Tree.SelectedNodes)
                    if app.Tree.SelectedNodes.Parent == app.ObstaclesNode
                        propertyType = "obstacle";
                    else
                        propertyType = "target";
                    end
                    enable_properties(app,propertyType);
                end

                app.paused = false;
                app.running = 0;
            end
            
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            remove_segment_lines(app)
            for i=1:height(app.TargetsNode.Children)
                delete(app.TargetsNode.Children(i).NodeData.child.Children);
            end
        end

        % Value changed function: FirstangleEditField, 
        % ...and 4 other components
        function nodesEditFieldValueChanged2(app, event)
            init_segments(app);
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            if ishandle(app.figS)
                figCloser(0,0,app)
            end
            changed = checkChanges(app);
            if changed
                saveVar.op = app.op;
                saveVar.OPTreeChildren = app.OPTree.Children;
                saveVar.MPTreeChildren = app.MPTree.Children;
                [file, path] = uiputfile('*.mat', 'MATLAB Data File');
                if isequal(file, 0)
                    % User canceled the operation
                else
                    % Combine the selected path and file name
                    fullFilePath = fullfile(path, file);

                    save(fullFilePath, 'saveVar');

                end
            end
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            changed = checkChanges(app);
            if changed
                selection = confirmAction(app,'New Document',"Loss of previous changes may occur.");
                if selection ~= "OK"
                    return;  
                end

            end
            if ishandle(app.figS)
                figCloser(0,0,app)
            end
            newDocument(app)
            toggleUI(app,"on",[app.Stop, app.Pauser, app.Continuar]);
            app.genHold = -10;
            app.holdNode = -10;

            loadDefaultOptions(app)

            drawMinMax(app);

            RestoreViewClicked(app, event)

            app.lastSelected = app.BaseNode.Children.NodeData.child;
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            changed = checkChanges(app);
            value = importFile(app);
            if value
                if changed
                    selection = confirmAction(app,'Open Document',"Loss of previous changes may occur.");
                    if selection ~= "OK"
                        return;

                    end
                end
                newDocument(app)
                if ishandle(app.figS)
                    figCloser(0,0,app)
                end
                toggleUI(app,"on",[app.Stop, app.Pauser, app.Continuar]);
                app.genHold = -10;
                app.holdNode = -10;
                parseDataIntoOP(app)
                children1 = app.loadedData.saveVar.OPTreeChildren;
                children2 = app.loadedData.saveVar.MPTreeChildren;
                if numel(children1) > 0
                    parseDataIntoOPTree(app)
                    OPTreeSelectionChanged(app,0); %If you want to use other way comment this
                end
                if numel(children2) > 0
                    parseDataIntoMPTree(app)
                    MPTreeSelectionChanged(app,1); %If you want to use other way comment this
                end
                app.Pointer.State = "on";
                % otherwise they'd not be clickable at first
                handle_hittest(app)
                app.animationsCount = size(app.MPTree.Children, 1);
                drawMinMax(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            changed = checkChanges(app);
            if changed
                selection = confirmAction(app,'Exit',"Loss of previous changes may occur.");
                if selection == "OK"
                    if ishandle(app.figS)
                        figCloser(0,0,app)
                    end
                    delete(app)
                end
            else
                if ishandle(app.figS)
                    figCloser(0,0,app)
                end
                delete(app)
            end


        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            try
                app.selectedTab = app.TabGroup3.SelectedTab;
                % Set the zoom factor (adjust as needed)
                zoomFactor = 0.9;
                % Get the current cursor coordinates
                currentPoint = app.UIAxes.CurrentPoint;
                x = currentPoint(1,1);
                y = currentPoint(1,2);
                
                % Get the current axis limits
                currentXLimits = app.UIAxes.XLim;
                currentYLimits = app.UIAxes.YLim;
                
                % Check if cursor coordinates are inside the axes limits
                insideAxes = x >= currentXLimits(1) && x <= currentXLimits(2) && ...
                              y >= currentYLimits(1) && y <= currentYLimits(2);
                
                if insideAxes
                    
                 
                    if event.VerticalScrollCount > 0
                        % Calculate the new axis limits for zooming out while keeping the center point fixed
                        newXLimits = (currentXLimits - x) / zoomFactor + x;
                        newYLimits = (currentYLimits - y) / zoomFactor + y;
                        if app.selectedTab ~= app.MotionPlannerTab
                            app.scalerOP = app.scalerOP / zoomFactor;
                            stable_zoom_Base(app);
                            stable_zoom(app);
                        else
                            app.scalerMP = app.scalerMP / zoomFactor;
                            stable_zoom_MP(app);
                        end
                    else
                        % Calculate the new axis limits for zooming in while keeping the center point fixed
                        newXLimits = (currentXLimits - x) * zoomFactor + x;
                        newYLimits = (currentYLimits - y) * zoomFactor + y;
                        if app.selectedTab ~= app.MotionPlannerTab
                            app.scalerOP = app.scalerOP * zoomFactor;
                            stable_zoom_Base(app);
                            stable_zoom(app);
                        else
                            app.scalerMP = app.scalerMP * zoomFactor;
                            stable_zoom_MP(app);
                        end
                    end
            
                    % Update the axis limits
                    app.UIAxes.XLim = newXLimits;
                    app.UIAxes.YLim = newYLimits;
                    drawMinMax(app);
                end
            catch er
                uialert(app.UIFigure, er.message, 'Error');
            end


        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            if value=="Genetic Algorithm"
                bbbcOptionsVisibility(app,"off");
                gaOptionsVisibility(app,"on");
            elseif value == "Big-Bang Big-Crunch"
                bbbcOptionsVisibility(app,"on");
                gaOptionsVisibility(app,"off");
            end
        end

        % Callback function: Stop
        function StopClicked(app, event)
            app.stopRunItGeneric = true;
            toggleUI(app,"on",[app.Stop, app.Pauser, app.Continuar]);
            
            if numel(app.OPTree.Children)>0
                app.OPTree.SelectedNodes = app.OPTree.Children(end);
            end
            
            OPTreeSelectionChanged(app, event)
            app.running = 0;
        end

        % Value changed function: SurvivalmethodDropDown
        function SurvivalmethodDropDownValueChanged(app, event)
            updateSurvivalAlphaVisibility(app, "on")
        end

        % Selection changed function: OPTree
        function OPTreeSelectionChanged(app, event)
            selectedNodes = app.OPTree.SelectedNodes;
            init_segments(app);
            drawRobotConfigurations(app,selectedNodes.NodeData.configurations)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.Pointer.State == "on"
                result = insideAxes(app);
                if result
                    app.UIFigure.WindowButtonMotionFcn = @(src,event)dragDropInMotion(app,src);
    
                    app.dragDropStart = app.UIAxes.CurrentPoint;
                end
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
                app.UIFigure.WindowButtonMotionFcn = [];
                changePointerTo(app,app.latestTool);
        end

        % Callback function: Animate
        function AnimateClicked(app, event)
            
            selectedNode = app.OPTree.SelectedNodes;
            if ishandle(app.figS)
                figCloser(0, 0, app);
            end
            % OP's output is empty? Terminate and if not continue
            if isempty(selectedNode)
                fig = app.UIFigure;
                % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                msg = '1. Run the optimizer.  2. select a solution you would like to animate.';
                title = 'Soft Robot Optimizer';
                uiconfirm(fig,msg,title, ...
                'Options',{'OK'}, ...
                'DefaultOption','OK','Icon','warning');
                return
            end
                % change tab 
                app.TabGroup3.SelectedTab = app.MotionPlannerTab;
        
                % fire the change in tabs event
                TabGroup3SelectionChanged(app, event)

                d = uiprogressdlg(app. UIFigure,'Title','Please Wait','Message','Searching for paths');
                d.Value = 0;
                pause(0.5)
                numberOfTargets = numel(app.op.targets(:,1));
                increment = 1/((numberOfTargets+1)*(numberOfTargets+1));

                paths=double.empty;

                numberOfResults = 0;
                home_conf = round(zeros(numel(app.OPTree.SelectedNodes.NodeData.design),2),3);
                app.OPTree.SelectedNodes.NodeData.configurations(:,:,end+1) = home_conf;
                disp(numberOfTargets)
                for from = 1: numberOfTargets+1
                    for to = 1: numberOfTargets+1
                        collectSP(app,from,to);
                        loadSP(app.sp);
                        result = MP_Solution_2D();
                        if numel(result) > 0
                            numberOfResults = numberOfResults + 1;
                        end
                        paths{from}{to} = result;
                        if d.Value + increment < 1
                            d.Value = d.Value + increment;
                        end

                    end
                end
                d.Value = 1;
                d.Message = 'Done';
                pause(0.5)
                close(d)

                % found a path?
                if numberOfResults > 0
                    
                    % copy OPTree's node to MPTree
                    arrayfun(@(x,y) copyobj(flipud(app.OPTree.SelectedNodes),y),app.OPTree,app.MPTree)

                    % Copy old nextAnimation
                    uitreenode(app.MPTree,'NodeData',app.MPTree.Children(end-1).NodeData,'Text',app.MPTree.Children(end-1).Text);

                    % delete old nextAnimation
                    delete(app.MPTree.Children(end-2))

                    % click node
                    node = app.MPTree.Children(end-1);

                    % store sp in node
                    node.NodeData.sp = app.sp;
                    app.MPTree.SelectedNodes = node;
                    if isempty(app.dummyNode)  
                        app.MPTree.SelectedNodes.NodeData.TargetNames = strings(height(app.TargetsNode.Children));  
                        for i=1:height(app.TargetsNode.Children)
                            if isempty(app.TargetsNode.Children(i).NodeData.child.Children)
                                datatip(app.TargetsNode.Children(i).NodeData.child);
                            end
                            app.MPTree.SelectedNodes.NodeData.TargetNames(i) = string(app.TargetsNode.Children(i).NodeData.child.Children.Content(3));
                            delete(app.TargetsNode.Children(i).NodeData.child.Children);
                        end
                    else
                        app.MPTree.SelectedNodes.NodeData.TargetNames = app.dummyNode; 
                    end

                    % store result in NodeData.path
                    app.MPTree.SelectedNodes.NodeData.paths = paths;

                    % fire MPTree selectChanged event
                    MPTreeSelectionChanged(app, event)
                    
                    % change text
                    if isempty(app.animationsCount)
                        app.animationsCount = 1;
                    end
                    node.Text = "Animation #" + string(app.animationsCount);
                    app.animationsCount = app.animationsCount + 1;
                    
                    % draw segment lines
                    draw_segments(app,app.UIAxes2)
                else
                    fig = app.UIFigure;
                    % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                    msg = 'No path found.';
                    title = 'Soft Robot Optimizer';
                    uiconfirm(fig,msg,title, ...
                    'Options',{'OK'}, ...
                    'DefaultOption','OK','Icon','info');
                end


        end

        % Selection change function: TabGroup3
        function TabGroup3SelectionChanged(app, event)
            selectedTabb = app.TabGroup3.SelectedTab;
            if selectedTabb == app.OptimizerTab
                app.selectedTab = app.TabGroup3.SelectedTab;
                app.UIAxes = app.UIAxes1;
            elseif selectedTabb == app.MotionPlannerTab
                app.selectedTab = app.TabGroup3.SelectedTab;
                app.UIAxes = app.UIAxes2;
            end
            % change cursor to arrow
            PointerOn(app,event);
        end

        % Menu selected function: AnimateMenu
        function AnimateMenuSelected(app, event)
            AnimateClicked(app, event);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if numel(app.MPTree.Children)>0
                cla(app.UIAxes2);
                drawTargetsObstaclesBaseinMP(app);
                draw_segments_MP(app,app.UIAxes2,app.MPTree.SelectedNodes);
                node = app.MPTree.SelectedNodes;
                if node.Text ~= "Next animation"
                    % disable everything
                    toggleUI(app,"off",[])
    
                    speed = app.SpeedperFrame1secto0secSlider.Value;
                    secondsToPause = (100-speed)/100;
                    paths = node.NodeData.paths;

                    from = 1;
                    if ~isfield(node.NodeData, 'mode')
                        animate_2D(app, app.sp, paths{end}{from}, [250, 250], secondsToPause, app.UIAxes2);
                    end
                    for i = 1:numel(node.NodeData.targets(:, 1))
                        app.MPRunning = true;
                        if ~isfield(node.NodeData, 'mode')
                            animate_2D(app, app.sp, paths{from}{from + 1}, [250, 250], secondsToPause, app.UIAxes2);
                        else
                            switch node.NodeData.mode
                                case "Carry & Drop Robot"
                                    animate_2D(app, app.sp, paths{end}{from}, [250, 250], secondsToPause, app.UIAxes2, i, true);
                                    drawTargetsObstaclesBaseinMP(app, i + 1, true);
                                    draw_segments_MP(app,app.UIAxes2,app.MPTree.SelectedNodes, i + 1, true);
                                    animate_2D_noReset(app, app.sp, paths{from}{end}, [250, 250], secondsToPause, app.UIAxes2);
                                case "Pick & Collect Robot"
                                    animate_2D_noReset(app, app.sp, paths{end}{from}, [250, 250], secondsToPause, app.UIAxes2);
                                    animate_2D(app, app.sp, paths{from}{end}, [250, 250], secondsToPause, app.UIAxes2, i, false);
                            end
                            
                        end
                        
                        from = from + 1;
                    
                    end

                    % enable everything again
                    if app.genHold == -10  
                        toggleUI(app,"on",[app.Stop, app.Continuar, app.Pauser])
                    else
                        toggleUI(app,"on",[app.Stop,app.Pauser])
                        buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                        for k = 1:length(buttons)
                            buttons(k).Enable = 'off';
                        end
                    end
                    app.previousX = round(node.NodeData.targets(1,1),3);
                    app.previousY = round(node.NodeData.targets(1,2),3);
                    % else
                    %     fig = app.UIFigure;
                    %     % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                    %     msg = 'No path found.';
                    %     title = 'Soft Robot Optimizer';
                    %     uiconfirm(fig,msg,title, ...
                    %     'Options',{'OK'}, ...
                    %     'DefaultOption','OK','Icon','info');
                    % end
                else
                    fig = app.UIFigure;
                    msg = '"Next animation" node is selected. Please pick a valid animation to play.';
                    title = 'Soft Robot Optimizer';
                    uiconfirm(fig,msg,title, ...
                        'Options',{'OK'}, ...
                        'DefaultOption','OK','Icon','info');
                end
            else
                fig = app.UIFigure;
                % msg = 'Please, first run the optimizer and then select a solution you would like to animate.';
                msg = 'No animations found.';
                title = 'Soft Robot Optimizer';
                uiconfirm(fig,msg,title, ...
                    'Options',{'OK'}, ...
                    'DefaultOption','OK','Icon','info');
            end
            app.MPRunning = false;


        end

        % Selection changed function: MPTree
        function MPTreeSelectionChanged(app, event)
            if app.MPTree.SelectedNodes.Text == "Next animation"
                nextAnimation = app.MPTree.SelectedNodes;
                app.TypeofalgorithmDropDown.Value = nextAnimation.NodeData.typeOfAlg ;

                app.SteeringEditField.Value = nextAnimation.NodeData.costArray(1);
                app.GrowingEditField.Value = nextAnimation.NodeData.costArray(2);
                app.RetractingEditField.Value = nextAnimation.NodeData.costArray(3);

                app.SteeringEditField_2.Value = nextAnimation.NodeData.stepSize(1);
                app.GrowingandretractingEditField.Value = nextAnimation.NodeData.stepSize(2);

                app.TypeofheuristicDropDown.Value = nextAnimation.NodeData.typeOfHeuristic;

                app.SimultaneousCheckBox.Value = nextAnimation.NodeData.isSimulataneously;
                app.BaseRotateCheckBox.Value = nextAnimation.NodeData.baseRotate;

                % delete shapes in uiaxes2
                delete(app.UIAxes2.Children)
                delete(app.Tree2.Children);
                delete(app.Tree2.SelectedNodes);
            else

                SPtoUI(app,app.MPTree.SelectedNodes.NodeData.sp);
                % delete everything in uiaxes2
                cla(app.UIAxes2)

                drawTargetsObstaclesBaseinMP(app)
                draw_segments_MP(app,app.UIAxes2,app.MPTree.SelectedNodes);
                delete(app.Tree2.Children);
                delete(app.Tree2.SelectedNodes);
                uitreenode(app.Tree2,'NodeData',app.MPTree.SelectedNodes.NodeData.base,'Text', 'Base', 'Icon', 'house-chimney.png');
                for i=1:height(app.MPTree.SelectedNodes.NodeData.targets)
                    uitreenode(app.Tree2,'NodeData',app.MPTree.SelectedNodes.NodeData.targets(i,:),'Text',app.MPTree.SelectedNodes.NodeData.TargetNames(i), 'Icon', 'star.png');
                end
                app.previousX = app.MPTree.SelectedNodes.NodeData.base(1);
                app.previousY = app.MPTree.SelectedNodes.NodeData.base(2);
            end
        end

        % Menu selected function: CopyMenu
        function CopyMenuSelected(app, event)
            selected = app.lastSelected;

            if selected.Selected == "off"
                return;
            end

            if selected.UserData.Parent == app.TargetsNode
                app.clipboard.type = "target";
                app.clipboard.x = selected.UserData.NodeData.x;
                app.clipboard.y = selected.UserData.NodeData.y;
                app.clipboard.angle = selected.UserData.NodeData.angle;
            elseif selected.UserData.Parent == app.ObstaclesNode
                app.clipboard.type = "obstacle";
                app.clipboard.x = selected.UserData.NodeData.x;
                app.clipboard.y = selected.UserData.NodeData.y;
                app.clipboard.radius = selected.UserData.NodeData.radius;
            elseif selected.UserData.Parent == app.BaseNode
                app.clipboard.type = "base";
            end

        end

        % Menu selected function: PasteMenu
        function PasteMenuSelected(app, event)
            if (~isfield(app.clipboard,'type')) 
                return;
            end
            if app.clipboard.type == "obstacle"
                % Add obstacle from clipboard
                obstacle = app.clipboard;
                create_polyshape(app,obstacle.x+(obstacle.radius)*2+5,obstacle.y+(obstacle.radius)*2+5,obstacle.radius,0,false);
            elseif app.clipboard.type == "target"
                % Add target from clipboard
                target = app.clipboard;
                create_polyshape(app,target.x+5,target.y+5,1,target.angle, true, app.scalerOP);
            end
            init_segments(app);
            CopyMenuSelected(app,event)
        end

        % Menu selected function: CutMenu
        function CutMenuSelected(app, event)
            CopyMenuSelected(app,event);
            if app.Tree.SelectedNodes.Parent == app.ObstaclesNode || app.Tree.SelectedNodes.Parent == app.TargetsNode
                deleteSelected(app);
                app.lastSelected = app.BaseNode.Children(end).NodeData.child;
            end
        end

        % Value changed function: MinlengthEditField
        function MinlengthEditFieldValueChanged(app, event)
            drawMinMax(app);
        end

        % Value changed function: MaxlengthEditField
        function MaxlengthEditFieldValueChanged(app, event)
            drawMinMax(app);            
        end

        % Menu selected function: ExportasPDFMenu
        function ExportasPDFMenuSelected(app, event)
            [file, path] = uiputfile('*.pdf', 'PDF');
            if isequal(file, 0)
                % User canceled the operation
            else
                % Combine the selected path and file name
                fullFilePath = fullfile(path, file);
                d = uiprogressdlg(app. UIFigure,'Title','Please Wait','Message','Exporting as PDF');
                pause(0.5)
                exportgraphics(app.UIAxes1,fullFilePath,'ContentType','vector')                               
                d.Value = 1;
                d.Message = 'Done';
                pause(0.5)
                close(d)                                        
            end
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Menu selected function: RunCase1Menu
        function RunCase1MenuSelected(app, event)
            runTestCase(app,1);
            drawMinMax(app);
        end

        % Menu selected function: RunCase2Menu
        function RunCase2MenuSelected(app, event)
            runTestCase(app,2);
            drawMinMax(app);
        end

        % Menu selected function: RunCase3Menu
        function RunCase3MenuSelected(app, event)
            runTestCase(app,3);
            drawMinMax(app);
        end

        % Menu selected function: RunCase4Menu
        function RunCase4MenuSelected(app, event)
            runTestCase(app,4);
            drawMinMax(app);
        end

        % Menu selected function: RunCase5Menu
        function RunCase5MenuSelected(app, event)
            runTestCase(app,5);
            drawMinMax(app);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            PushToolClicked(app, event);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            PushTool3Clicked(app, event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            PushTool2Clicked(app, event);
        end

        % Menu selected function: RunOptimizerMenu
        function RunOptimizerMenuSelected(app, event)
            RunClicked(app, event);
        end

        % Menu selected function: RunMotionPlannerMenu
        function RunMotionPlannerMenuSelected(app, event)
            AnimateClicked(app, event);
        end

        % Value changed function: BaseRotateCheckBox, GrowingEditField, 
        % ...and 6 other components
        function TypeofalgorithmDropDownValueChanged(app, event)
            selectedNode = app.MPTree.SelectedNodes;
            if selectedNode.Text == "Next animation"
                selectedNode.NodeData.typeOfAlg = app.TypeofalgorithmDropDown.Value;

                selectedNode.NodeData.costArray = [app.SteeringEditField.Value app.GrowingEditField.Value app.RetractingEditField.Value];
                selectedNode.NodeData.stepSize = [app.SteeringEditField_2.Value app.GrowingandretractingEditField.Value];

                selectedNode.NodeData.typeOfHeuristic = app.TypeofheuristicDropDown.Value;

                selectedNode.NodeData.isSimulataneously = app.SimultaneousCheckBox.Value;
                selectedNode.NodeData.baseRotate = app.BaseRotateCheckBox.Value;
            else
                app.dummyNode = selectedNode.NodeData.TargetNames;
                selectedNode = app.MPTree.Children(end);
                confirm = confirmAction(app,"Motion Planner","Apply changes?");
                if confirm == "OK"
                    selectedNode.NodeData.typeOfAlg = app.TypeofalgorithmDropDown.Value;

                    selectedNode.NodeData.costArray = [app.SteeringEditField.Value app.GrowingEditField.Value app.RetractingEditField.Value];
                    selectedNode.NodeData.stepSize = [app.SteeringEditField_2.Value app.GrowingandretractingEditField.Value];

                    selectedNode.NodeData.typeOfHeuristic = app.TypeofheuristicDropDown.Value;

                    selectedNode.NodeData.isSimulataneously = app.SimultaneousCheckBox.Value;
                    selectedNode.NodeData.baseRotate = app.BaseRotateCheckBox.Value;

                    % add node to OPTree
                    
                    arrayfun(@(x,y) copyobj(flipud(app.MPTree.SelectedNodes),y),app.MPTree,app.OPTree)
                    
                    % click node
                    app.OPTree.SelectedNodes = app.OPTree.Children(end);

                    % call animator
                    AnimateClicked(app,event)

                    % delete node
                    delete(app.OPTree.Children(end))

                    app.dummyNode = [];
                end
            end
        end

        % Callback function: Pauser
        function PauserClicked(app, event)
            app.paused = true;

            app.running = 0;

        end

        % Callback function: Continuar
        function ContinuarClicked(app, event)
            app.paused = false;
            if ishandle(app.figS)
                figCloser(0,0,app)
            end
            if app.running == 0
                app.running = 1;
    
                if numel(app.TargetsNode.Children)>0
                    try
                        toggleUI(app, "off",[app.Stop, app.Pauser]);
                        
                        result = doesOverlap(app);
                        if ~result
                            app.stopRunItGeneric = false;
                            sendRunItGeneric(app);
                            [~,~] = runIt_generic(app);
                        end
                        toggleUI(app, "on", [app.Stop, app.Pauser]);
                        if app.paused == true && app.genHold ~= app.GenerationsEditField.Value
                            buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                            for k = 1:length(buttons)
                                buttons(k).Enable = 'off';
                            end
                        else
                            app.Continuar.Enable = 'off';
                        end
                    catch er
                        uialert(app.UIFigure, er.message, 'Error');
    
                    end
                else
                    fig = app.UIFigure;
                    msg = 'No targets found.';
                    title = 'Soft Robot Optimizer';
                    uiconfirm(fig,msg,title, ...
                        'Options',{'OK'}, ...
                        'DefaultOption','OK','Icon','warning');
                end
                
                if app.Tree.SelectedNodes.Parent == app.ObstaclesNode
                    propertyType = "obstacle";
                else
                    propertyType = "target";
                end
                
                enable_properties(app,propertyType);
                app.paused = false;
                app.running = 0;
            end
        end

        % Button pushed function: Rotator
        function RotatorPushed(app, event)
            if numel(app.OPTree.Children)>1 && app.Eraser.State == "off"
                result = confirmAction(app,'Editing',"Loss in Optimizer's Output Will Occur");
                if result  == "OK"
                    delete(app.OPTree.Children);
                    app.genHold = -10;
                    app.holdNode = -10;
                    buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                    for k = 1:length(buttons)
                        buttons(k).Enable = 'on';
                    end
                    app.Continuar.Enable = 'off';
            
                else
                    return;
                end
        
            end
            if ~ishandle(app.figS)
                createRotator(app);
            else
                figCloser(0, 0, app);
            end
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            mouseClickInMP(app, selectedNodes, true);
            app.Tree2.SelectedNodes = [];
        end

        % Value changed function: TargetCollisionCheckBox
        function TargetCollisionCheckBoxValueChanged(app, event)

        end

        % Value changed function: RobotModeDropDown
        function RobotModeDropDownValueChanged(app, event)
            init_segments(app);
        end

        % Callback function: PushTool5
        function PushTool5Clicked(app, event)
            allData = zeros(1, 20);
            folderPath = fullfile(getenv('USERPROFILE'), 'Desktop', 'Experiment', 'TC2', 'Vacuum');

            for i = 1:20
                fileName = fullfile(folderPath, sprintf('VacuumTC2_%d', i));
            
                value = importFile(app, fileName);
            
                if value
            
                    newDocument(app);
                    if ishandle(app.figS)
                        figCloser(0, 0, app);
                    end
                    toggleUI(app, "on", [app.Stop, app.Pauser, app.Continuar]);
            
                    app.genHold = -10;
                    app.holdNode = -10;
                    parseDataIntoOP(app);
            
                    children1 = app.loadedData.saveVar.OPTreeChildren;
                    children2 = app.loadedData.saveVar.MPTreeChildren;
            
                    if numel(children1) > 0
                        parseDataIntoOPTree(app);
                        OPTreeSelectionChanged(app, 0);
                    end
                    if numel(children2) > 0
                        parseDataIntoMPTree(app);
                        MPTreeSelectionChanged(app, 1);
                    end
            
                    app.Pointer.State = "on";
                    handle_hittest(app);
                    app.animationsCount = size(app.MPTree.Children, 1);
                    drawMinMax(app);
                    
                    selectedNode = app.OPTree.SelectedNodes;
                    selectedNode.Icon
                    if selectedNode.Icon == "Red-Cross.png"
                        continue;
                    end

                    AnimateClicked(app, event);
                    if isempty(app.Tree2.Children)
                        continue;
                    end

                    total = 0;
                    app.Tree2.SelectedNodes = app.Tree2.Children(2);
                    selectedNodes = app.Tree2.SelectedNodes;
                    total = mouseClickInMP(app, selectedNodes, true, total);
                    % app.Tree2.SelectedNodes = app.Tree2.Children(1);
                    % selectedNodes = app.Tree2.SelectedNodes;
                    % total = mouseClickInMP(app, selectedNodes, true, total);
                    app.Tree2.SelectedNodes = app.Tree2.Children(3);
                    selectedNodes = app.Tree2.SelectedNodes;
                    total = mouseClickInMP(app, selectedNodes, true, total);
                    app.Tree2.SelectedNodes = app.Tree2.Children(1);
                    selectedNodes = app.Tree2.SelectedNodes;
                    total = mouseClickInMP(app, selectedNodes, true, total);

                    allData(i) = total;
                else
                    warning('Failed to import file: %s', fileName);
                end
            end
            allData
        end

        % Callback function: PushTool6
        function PushTool6Clicked(app, event)
            robotModes = ["Vacuum Robot"];
            subfolders = ["Vacuum"];
            desktopPath = fullfile(getenv('USERPROFILE'), 'Desktop');
    
            basePath = fullfile(desktopPath, 'Experiment/TC2');
            tcSuffix = "TC2";
            shitface = fullfile(basePath, sprintf('Default/DefaultTC2_5'));
            value = importFile(app, char(shitface));
            if value
    
                newDocument(app);
                if ishandle(app.figS)
                    figCloser(0, 0, app);
                end
                toggleUI(app, "on", [app.Stop, app.Pauser, app.Continuar]);
        
                app.genHold = -10;
                app.holdNode = -10;
                parseDataIntoOP(app);
        
                children1 = app.loadedData.saveVar.OPTreeChildren;
                children2 = app.loadedData.saveVar.MPTreeChildren;
        
                if numel(children1) > 0
                    parseDataIntoOPTree(app);
                    OPTreeSelectionChanged(app, 0);
                end
                if numel(children2) > 0
                    parseDataIntoMPTree(app);
                    MPTreeSelectionChanged(app, 1);
                end
        
                app.Pointer.State = "on";
                handle_hittest(app);
                app.animationsCount = size(app.MPTree.Children, 1);
                drawMinMax(app);
            end

            for j = 1:1
                app.RobotModeDropDown.Value = robotModes(j);
                subfolder = subfolders(j);
                fullPath = fullfile(basePath, subfolder);
                

                kStart = 1;
                kEnd = 20;
            
                for k = kStart:kEnd
                    app.paused = false;
                    app.genHold = -10;
                    app.holdNode = -10;
                    if ishandle(app.figS)
                        figCloser(0,0,app)
                    end
                    if app.running == 0
                        app.running = 1;
            
                        if numel(app.TargetsNode.Children) > 0
                            try
                                delete(app.OPTree.Children);
                                toggleUI(app, "off", [app.Stop, app.Pauser]);
            
                                result = doesOverlap(app);
            
                                if ~result && ~(app.RobotModeDropDown.Value == "Carry Robot" && app.TargetCollisionCheckBox.Value && any(mod(size(app.op.targets), 2) == 1))
                                    app.stopRunItGeneric = false;
                                    sendRunItGeneric(app);
                                    [~,~] = runIt_generic(app);
                                end
                                toggleUI(app, "on", [app.Stop, app.Pauser]);
            
                                if app.paused == true && app.genHold ~= app.GenerationsEditField.Value
                                    buttons = findall(app.TabGroup2, 'Tag', 'robotPara');
                                    for k = 1:length(buttons)
                                        buttons(k).Enable = 'off';
                                    end
                                else
                                    app.Continuar.Enable = 'off';
                                end
            
                            catch er
                                uialert(app.UIFigure, er.message, 'Error');
                            end
                        else
                            fig = app.UIFigure;
                            msg = 'No targets found.';
                            title = 'Soft Robot Optimizer';
                            uiconfirm(fig, msg, title, 'Options', {'OK'}, 'DefaultOption', 'OK', 'Icon', 'warning');
                        end
            
                        if ~isempty(app.Tree.SelectedNodes)
                            if app.Tree.SelectedNodes.Parent == app.ObstaclesNode
                                propertyType = "obstacle";
                            else
                                propertyType = "target";
                            end
                            enable_properties(app, propertyType);
                        end
            
                        app.paused = false;
                        app.running = 0;
                    end
            
                    if ishandle(app.figS)
                        figCloser(0,0,app)
                    end
            
                    changed = checkChanges(app);
                    if changed
                        saveVar.op = app.op;
                        saveVar.OPTreeChildren = app.OPTree.Children;
                        saveVar.MPTreeChildren = app.MPTree.Children;
                        file = sprintf("%s%s_%d.mat", subfolder, tcSuffix, k);
                        fullFilePath = fullfile(fullPath, file);
                        save(fullFilePath, 'saveVar');
                    end
                end
            end


        end

        % Callback function: PushTool7
        function PushTool7Clicked(app, event)
            figFile = 'myPlot.pdf';
            exportgraphics(app.UIAxes1, figFile, 'ContentType', 'vector');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ExportasPDFMenu = uimenu(app.FileMenu);
            app.ExportasPDFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasPDFMenuSelected, true);
            app.ExportasPDFMenu.Accelerator = 'p';
            app.ExportasPDFMenu.Text = 'Export as PDF';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.MenuSelectedFcn = createCallbackFcn(app, @CutMenuSelected, true);
            app.CutMenu.Accelerator = 'x';
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyMenuSelected, true);
            app.CopyMenu.Accelerator = 'c';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.MenuSelectedFcn = createCallbackFcn(app, @PasteMenuSelected, true);
            app.PasteMenu.Accelerator = 'v';
            app.PasteMenu.Text = 'Paste';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunOptimizerMenu = uimenu(app.RunMenu);
            app.RunOptimizerMenu.MenuSelectedFcn = createCallbackFcn(app, @RunOptimizerMenuSelected, true);
            app.RunOptimizerMenu.Accelerator = 'r';
            app.RunOptimizerMenu.Text = 'Run Optimizer';
            app.RunMotionPlannerMenu = uimenu(app.RunMenu);
            app.RunMotionPlannerMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMotionPlannerMenuSelected, true);
            app.RunMotionPlannerMenu.Accelerator = 't';
            app.RunMotionPlannerMenu.Text = 'Run Motion Planner';
            app.TestsMenu = uimenu(app.UIFigure);
            app.TestsMenu.Text = 'Tests';
            app.RunCase1Menu = uimenu(app.TestsMenu);
            app.RunCase1Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase1MenuSelected, true);
            app.RunCase1Menu.Text = 'Run Case 1';
            app.RunCase2Menu = uimenu(app.TestsMenu);
            app.RunCase2Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase2MenuSelected, true);
            app.RunCase2Menu.Text = 'Run Case 2';
            app.RunCase3Menu = uimenu(app.TestsMenu);
            app.RunCase3Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase3MenuSelected, true);
            app.RunCase3Menu.Text = 'Run Case 3';
            app.RunCase4Menu = uimenu(app.TestsMenu);
            app.RunCase4Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase4MenuSelected, true);
            app.RunCase4Menu.Text = 'Run Case 4';
            app.RunCase5Menu = uimenu(app.TestsMenu);
            app.RunCase5Menu.MenuSelectedFcn = createCallbackFcn(app, @RunCase5MenuSelected, true);
            app.RunCase5Menu.Text = 'Run Case 5';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New document'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'add-document.png';
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save document'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'disk.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open document'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'folder-open.png';
            app.Pointer = uitoggletool(app.Toolbar);
            app.Pointer.State = 'on';
            app.Pointer.Tooltip = {'Select'};
            app.Pointer.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Pointer.Icon = 'cursor.png';
            app.Pointer.Separator = 'on';
            app.Pointer.OnCallback = createCallbackFcn(app, @PointerOn, true);
            app.Target = uitoggletool(app.Toolbar);
            app.Target.Tooltip = {'Target'};
            app.Target.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Target.Icon = 'star.png';
            app.Target.OnCallback = createCallbackFcn(app, @TargetOn2, true);
            app.Obstacle = uitoggletool(app.Toolbar);
            app.Obstacle.Tooltip = {'Obstacle'};
            app.Obstacle.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Obstacle.Icon = 'circle.png';
            app.Obstacle.OnCallback = createCallbackFcn(app, @ObstacleOn, true);
            app.Eraser = uitoggletool(app.Toolbar);
            app.Eraser.Tooltip = {'Eraser'};
            app.Eraser.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Eraser.Icon = 'eraser.png';
            app.Eraser.OnCallback = createCallbackFcn(app, @EraserOn, true);
            app.Run = uipushtool(app.Toolbar);
            app.Run.Tooltip = {'Run Optimizer'};
            app.Run.ClickedCallback = createCallbackFcn(app, @RunClicked, true);
            app.Run.Icon = 'play.png';
            app.Run.Separator = 'on';
            app.Pauser = uipushtool(app.Toolbar);
            app.Pauser.Tooltip = {'Pause'};
            app.Pauser.ClickedCallback = createCallbackFcn(app, @PauserClicked, true);
            app.Pauser.Enable = 'off';
            app.Pauser.Icon = 'pause.svg';
            app.Continuar = uipushtool(app.Toolbar);
            app.Continuar.Tooltip = {'Continue'};
            app.Continuar.ClickedCallback = createCallbackFcn(app, @ContinuarClicked, true);
            app.Continuar.Enable = 'off';
            app.Continuar.Icon = 'continue.svg';
            app.Stop = uipushtool(app.Toolbar);
            app.Stop.Tooltip = {'Stop'};
            app.Stop.ClickedCallback = createCallbackFcn(app, @StopClicked, true);
            app.Stop.Enable = 'off';
            app.Stop.Icon = 'stop.png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Clean'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'broom.png';
            app.Animate = uipushtool(app.Toolbar);
            app.Animate.Tooltip = {'Run Motion Planner'};
            app.Animate.ClickedCallback = createCallbackFcn(app, @AnimateClicked, true);
            app.Animate.Icon = 'animated-icon.png';
            app.RestoreView = uipushtool(app.Toolbar);
            app.RestoreView.Tooltip = {'Restore view'};
            app.RestoreView.ClickedCallback = createCallbackFcn(app, @RestoreViewClicked, true);
            app.RestoreView.Icon = 'house-chimney.png';
            app.RestoreView.Separator = 'on';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.ClickedCallback = createCallbackFcn(app, @PushTool7Clicked, true);
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.TabGroup3 = uitabgroup(app.GridLayout6);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TabGroup3SelectionChanged, true);
            app.OptimizerTab = uitab(app.TabGroup3);
            app.OptimizerTab.Title = 'Optimizer';
            app.GridLayout = uigridlayout(app.OptimizerTab);
            app.UIAxes1 = uiaxes(app.GridLayout);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.ButtonDownFcn = createCallbackFcn(app, @UIAxes1ButtonDown3, true);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.OPTree = uitree(app.GridLayout);
            app.OPTree.SelectionChangedFcn = createCallbackFcn(app, @OPTreeSelectionChanged, true);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.ObjectsLabel = uilabel(app.GridLayout);
            app.ObjectsLabel.Text = 'Objects';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.BaseNode = uitreenode(app.Tree);
            app.BaseNode.Icon = 'house-chimney.png';
            app.BaseNode.Text = 'Base';
            app.TargetsNode = uitreenode(app.Tree);
            app.TargetsNode.Icon = 'star.png';
            app.TargetsNode.Text = 'Targets';
            app.ObstaclesNode = uitreenode(app.Tree);
            app.ObstaclesNode.Icon = 'circle.png';
            app.ObstaclesNode.Text = 'Obstacles';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.OptionsLabel = uilabel(app.GridLayout);
            app.OptionsLabel.Text = 'Options';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout3 = uigridlayout(app.PropertiesTab);
            app.XcoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.XcoordinateEditFieldLabel.Text = 'X-coordinate ';
            app.XcoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.YcoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.YcoordinateEditFieldLabel.Text = 'Y-coordinate ';
            app.YcoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.GridLayout3);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.AngledegEditFieldLabel = uilabel(app.GridLayout3);
            app.AngledegEditFieldLabel.Text = 'Angle (deg)';
            app.AngledegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AngledegEditField.Limits = [-360 360];
            app.AngledegEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.Rotator = uibutton(app.GridLayout3, 'push');
            app.Rotator.ButtonPushedFcn = createCallbackFcn(app, @RotatorPushed, true);
            app.Rotator.Icon = 'Rotate.png';
            app.Rotator.Text = '';
            app.TargetCollisionRadiusEditFieldLabel = uilabel(app.GridLayout3);
            app.TargetCollisionRadiusEditFieldLabel.Text = 'Target Collision Radius';
            app.TargetCollisionRadiusEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TargetCollisionRadiusEditField.Limits = [1 Inf];
            app.TargetCollisionRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.TargetCollisionRadiusEditField.Value = 1;
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.RobotParametersTab = uitab(app.TabGroup2);
            app.RobotParametersTab.Title = 'Robot Parameters';
            app.GridLayout2 = uigridlayout(app.RobotParametersTab);
            app.LinksEditFieldLabel = uilabel(app.GridLayout2);
            app.LinksEditFieldLabel.Tag = 'robotPara';
            app.LinksEditFieldLabel.Text = 'Links';
            app.LinksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LinksEditField.Limits = [0 Inf];
            app.LinksEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.LinksEditField.Tag = 'robotPara';
            app.MinangleEditFieldLabel = uilabel(app.GridLayout2);
            app.MinangleEditFieldLabel.Tag = 'robotPara';
            app.MinangleEditFieldLabel.Text = 'Min angle';
            app.MinangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinangleEditField.Limits = [-360 360];
            app.MinangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.MinangleEditField.Tag = 'robotPara';
            app.MaxangleEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxangleEditFieldLabel.Tag = 'robotPara';
            app.MaxangleEditFieldLabel.Text = 'Max angle';
            app.MaxangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxangleEditField.Limits = [-360 360];
            app.MaxangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.MaxangleEditField.Tag = 'robotPara';
            app.MinlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinlengthEditField.Limits = [0 Inf];
            app.MinlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinlengthEditFieldValueChanged, true);
            app.MinlengthEditField.Tag = 'robotPara';
            app.MinlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MinlengthEditFieldLabel.Tag = 'robotPara';
            app.MinlengthEditFieldLabel.Text = 'Min length';
            app.MaxlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxlengthEditField.Limits = [0 Inf];
            app.MaxlengthEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlengthEditFieldValueChanged, true);
            app.MaxlengthEditField.Tag = 'robotPara';
            app.MaxlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxlengthEditFieldLabel.Tag = 'robotPara';
            app.MaxlengthEditFieldLabel.Text = 'Max length';
            app.RobotModeDropDownLabel = uilabel(app.GridLayout2);
            app.RobotModeDropDownLabel.Tag = 'robotPara';
            app.RobotModeDropDownLabel.Text = 'Robot Mode';
            app.RobotModeDropDown = uidropdown(app.GridLayout2);
            app.RobotModeDropDown.Items = {'Touch Robot', 'Vacuum Robot', 'Pick & Collect Robot', 'Carry & Drop Robot', 'Pick & Place Robot'};
            app.RobotModeDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotModeDropDownValueChanged, true);
            app.RobotModeDropDown.Tag = 'robotPara';
            app.RobotModeDropDown.Value = 'Touch Robot';
            app.TargetCollisionCheckBox = uicheckbox(app.GridLayout2);
            app.TargetCollisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetCollisionCheckBoxValueChanged, true);
            app.TargetCollisionCheckBox.Tag = 'robotPara';
            app.TargetCollisionCheckBox.Text = 'Target Collision';
            app.TargetCollisionCheckBox.Value = true;
            app.MaterialLossCheckBox = uicheckbox(app.GridLayout2);
            app.MaterialLossCheckBox.Tag = 'robotPara';
            app.MaterialLossCheckBox.Text = 'Simulate Material Loss While Bending';
            app.MaterialLossCheckBox.Value = true;
            app.RobotRadiusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RobotRadiusEditField.Limits = [0 Inf];
            app.RobotRadiusEditField.Tag = 'robotPara';
            app.RobotRadiusEditField.Value = 15;
            app.RobotRadiusEditFieldLabel = uilabel(app.GridLayout2);
            app.RobotRadiusEditFieldLabel.Tag = 'robotPara';
            app.RobotRadiusEditFieldLabel.Text = 'Robot Radius';
            app.OptimizationAlgorithmTab = uitab(app.TabGroup2);
            app.OptimizationAlgorithmTab.Title = 'Optimization Algorithm';
            app.GridLayout4 = uigridlayout(app.OptimizationAlgorithmTab);
            app.ObstacleavoidanceCheckBox = uicheckbox(app.GridLayout4);
            app.ObstacleavoidanceCheckBox.Tag = 'robotPara';
            app.ObstacleavoidanceCheckBox.Text = 'Obstacle avoidance';
            app.SelectionmethodDropDownLabel = uilabel(app.GridLayout4);
            app.SelectionmethodDropDownLabel.Tag = 'robotPara';
            app.SelectionmethodDropDownLabel.Text = 'Selection method';
            app.SelectionmethodDropDown = uidropdown(app.GridLayout4);
            app.SelectionmethodDropDown.Items = {'tournament', 'proportionate'};
            app.SelectionmethodDropDown.Tag = 'robotPara';
            app.SelectionmethodDropDown.Value = 'tournament';
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout4);
            app.AlgorithmDropDownLabel.Tag = 'robotPara';
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout4);
            app.AlgorithmDropDown.Items = {'Genetic Algorithm', 'Big-Bang Big-Crunch'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Tag = 'robotPara';
            app.AlgorithmDropDown.Value = 'Genetic Algorithm';
            app.GenerationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GenerationsEditField.Limits = [0 Inf];
            app.GenerationsEditField.Tag = 'robotPara';
            app.GenerationsEditFieldLabel = uilabel(app.GridLayout4);
            app.GenerationsEditFieldLabel.Tag = 'robotPara';
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.IndividualsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IndividualsEditField.Limits = [0 Inf];
            app.IndividualsEditField.Tag = 'robotPara';
            app.IndividualsEditFieldLabel = uilabel(app.GridLayout4);
            app.IndividualsEditFieldLabel.Tag = 'robotPara';
            app.IndividualsEditFieldLabel.Text = 'Individuals';
            app.CrossovermethodDropDown = uidropdown(app.GridLayout4);
            app.CrossovermethodDropDown.Items = {'blxa'};
            app.CrossovermethodDropDown.Tag = 'robotPara';
            app.CrossovermethodDropDown.Value = 'blxa';
            app.CrossovermethodDropDownLabel = uilabel(app.GridLayout4);
            app.CrossovermethodDropDownLabel.Tag = 'robotPara';
            app.CrossovermethodDropDownLabel.Text = 'Crossover method';
            app.CrossoverprobabilitySpinner = uispinner(app.GridLayout4);
            app.CrossoverprobabilitySpinner.Step = 0.1;
            app.CrossoverprobabilitySpinner.Limits = [0 1];
            app.CrossoverprobabilitySpinner.Tag = 'robotPara';
            app.CrossoverprobabilitySpinnerLabel = uilabel(app.GridLayout4);
            app.CrossoverprobabilitySpinnerLabel.Tag = 'robotPara';
            app.CrossoverprobabilitySpinnerLabel.Text = 'Crossover probability';
            app.PenaltymethodDropDown = uidropdown(app.GridLayout4);
            app.PenaltymethodDropDown.Items = {'static', 'deb'};
            app.PenaltymethodDropDown.Tag = 'robotPara';
            app.PenaltymethodDropDown.Visible = 'off';
            app.PenaltymethodDropDown.Value = 'static';
            app.PenaltymethodDropDownLabel = uilabel(app.GridLayout4);
            app.PenaltymethodDropDownLabel.Visible = 'off';
            app.PenaltymethodDropDownLabel.Text = 'Penalty method';
            app.RankPartitioningAlgorithmTab = uitab(app.TabGroup2);
            app.RankPartitioningAlgorithmTab.Title = 'Rank Partitioning Algorithm';
            app.GridLayout5 = uigridlayout(app.RankPartitioningAlgorithmTab);
            app.SteplengthEditFieldLabel = uilabel(app.GridLayout5);
            app.SteplengthEditFieldLabel.Tag = 'robotPara';
            app.SteplengthEditFieldLabel.Text = 'Step length';
            app.SteplengthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.SteplengthEditField.Limits = [0 Inf];
            app.SteplengthEditField.Tag = 'robotPara';
            app.StepIKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.StepIKEditField.Limits = [0 Inf];
            app.StepIKEditField.Tag = 'robotPara';
            app.StepIKEditFieldLabel = uilabel(app.GridLayout5);
            app.StepIKEditFieldLabel.Tag = 'robotPara';
            app.StepIKEditFieldLabel.Text = 'Step IK';
            app.MethodDropDownLabel = uilabel(app.GridLayout5);
            app.MethodDropDownLabel.Tag = 'robotPara';
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout5);
            app.MethodDropDown.Items = {'penalty'};
            app.MethodDropDown.Tag = 'robotPara';
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'penalty';
            app.MotionPlannerTab = uitab(app.TabGroup3);
            app.MotionPlannerTab.Title = 'Motion Planner';
            app.GridLayout7 = uigridlayout(app.MotionPlannerTab);
            app.UIAxes2 = uiaxes(app.GridLayout7);
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.TabGroup4 = uitabgroup(app.GridLayout7);
            app.StepSizeTab = uitab(app.TabGroup4);
            app.StepSizeTab.Title = 'Step Size';
            app.GridLayout3_3 = uigridlayout(app.StepSizeTab);
            app.SteeringEditField_2Label = uilabel(app.GridLayout3_3);
            app.SteeringEditField_2Label.Text = 'Steering';
            app.SteeringEditField_2 = uieditfield(app.GridLayout3_3, 'numeric');
            app.SteeringEditField_2.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.GrowingandretractingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.GrowingandretractingEditFieldLabel.Text = 'Growing and retracting';
            app.GrowingandretractingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.GrowingandretractingEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofalgorithmDropDownLabel = uilabel(app.GridLayout3_3);
            app.TypeofalgorithmDropDownLabel.Visible = 'off';
            app.TypeofalgorithmDropDownLabel.Text = 'Type of algorithm';
            app.TypeofalgorithmDropDown = uidropdown(app.GridLayout3_3);
            app.TypeofalgorithmDropDown.Items = {'A*', 'UCS', 'Greedy'};
            app.TypeofalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofalgorithmDropDown.Visible = 'off';
            app.TypeofalgorithmDropDown.Value = 'A*';
            app.TypeofheuristicDropDownLabel = uilabel(app.GridLayout3_3);
            app.TypeofheuristicDropDownLabel.Visible = 'off';
            app.TypeofheuristicDropDownLabel.Text = 'Type of heuristic';
            app.TypeofheuristicDropDown = uidropdown(app.GridLayout3_3);
            app.TypeofheuristicDropDown.Items = {'Continuous'};
            app.TypeofheuristicDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.TypeofheuristicDropDown.Visible = 'off';
            app.TypeofheuristicDropDown.Value = 'Continuous';
            app.SimultaneousCheckBox = uicheckbox(app.GridLayout3_3);
            app.SimultaneousCheckBox.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.SimultaneousCheckBox.Visible = 'off';
            app.SimultaneousCheckBox.Text = 'Simultaneous';
            app.BaseRotateCheckBox = uicheckbox(app.GridLayout3_3);
            app.BaseRotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.BaseRotateCheckBox.Visible = 'off';
            app.BaseRotateCheckBox.Text = 'Base Rotate';
            app.SteeringEditField_3Label = uilabel(app.GridLayout3_3);
            app.SteeringEditField_3Label.Visible = 'off';
            app.SteeringEditField_3Label.Text = 'Steering';
            app.SteeringEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.SteeringEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.SteeringEditField.Visible = 'off';
            app.GrowingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.GrowingEditFieldLabel.Visible = 'off';
            app.GrowingEditFieldLabel.Text = 'Growing';
            app.GrowingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.GrowingEditField.ValueChangedFcn = createCallbackFcn(app, @TypeofalgorithmDropDownValueChanged, true);
            app.GrowingEditField.Visible = 'off';
            app.RetractingEditFieldLabel = uilabel(app.GridLayout3_3);
            app.RetractingEditFieldLabel.Visible = 'off';
            app.RetractingEditFieldLabel.Text = 'Retracting';
            app.RetractingEditField = uieditfield(app.GridLayout3_3, 'numeric');
            app.RetractingEditField.Limits = [0 Inf];
            app.RetractingEditField.Visible = 'off';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Button_5 = uibutton(app.GridLayout8, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'play2.png';
            app.Button_5.Text = '';
            app.SpeedperFrame1secto0secSliderLabel = uilabel(app.GridLayout8);
            app.SpeedperFrame1secto0secSliderLabel.Text = 'Speed per Frame (1 sec to 0 sec)';
            app.SpeedperFrame1secto0secSlider = uislider(app.GridLayout8);
            app.SpeedperFrame1secto0secSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SpeedperFrame1secto0secSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '', '0'};
            app.SpeedperFrame1secto0secSlider.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.AnimationsLabel = uilabel(app.GridLayout7);
            app.AnimationsLabel.Text = 'Animations';
            app.MPTree = uitree(app.GridLayout7);
            app.MPTree.SelectionChangedFcn = createCallbackFcn(app, @MPTreeSelectionChanged, true);
            app.NextanimationNode = uitreenode(app.MPTree);
            app.NextanimationNode.Text = 'Next animation';
            app.Tree2 = uitree(app.GridLayout7);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.CrunchmethodDropDown = uidropdown(app.UIFigure);
            app.CrunchmethodDropDown.Items = {'fittest', 'com'};
            app.CrunchmethodDropDown.Tag = 'robotPara';
            app.CrunchmethodDropDown.Visible = 'off';
            app.CrunchmethodDropDown.Value = 'fittest';
            app.CrunchmethodDropDownLabel = uilabel(app.UIFigure);
            app.CrunchmethodDropDownLabel.Visible = 'off';
            app.CrunchmethodDropDownLabel.Text = 'Crunch method';
            app.MutationprobabilitySpinnerLabel = uilabel(app.UIFigure);
            app.MutationprobabilitySpinnerLabel.Text = 'Mutation probability';
            app.MutationprobabilitySpinner = uispinner(app.UIFigure);
            app.MutationprobabilitySpinner.Step = 0.1;
            app.MutationprobabilitySpinner.Limits = [0 1];
            app.MutationprobabilitySpinner.Tag = 'robotPara';
            app.SurvivalmethodDropDownLabel = uilabel(app.UIFigure);
            app.SurvivalmethodDropDownLabel.Text = 'Survival method';
            app.SurvivalmethodDropDown = uidropdown(app.UIFigure);
            app.SurvivalmethodDropDown.Items = {'elitist_full', 'elitist_alpha'};
            app.SurvivalmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SurvivalmethodDropDownValueChanged, true);
            app.SurvivalmethodDropDown.Tag = 'robotPara';
            app.SurvivalmethodDropDown.Value = 'elitist_full';
            app.SurvivalalphaLabel = uilabel(app.UIFigure);
            app.SurvivalalphaLabel.Text = 'Survival alpha %';
            app.SurvivalalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SurvivalalphaEditField.Limits = [0 100];
            app.SurvivalalphaEditField.Tag = 'robotPara';
            app.MutationmethodDropDown = uidropdown(app.UIFigure);
            app.MutationmethodDropDown.Items = {'random', 'modifiedRandomMutation'};
            app.MutationmethodDropDown.Tag = 'robotPara';
            app.MutationmethodDropDown.Value = 'random';
            app.MutationmethodDropDownLabel = uilabel(app.UIFigure);
            app.MutationmethodDropDownLabel.Text = 'Mutation method';
            app.FirstangleEditFieldLabel = uilabel(app.UIFigure);
            app.FirstangleEditFieldLabel.Enable = 'off';
            app.FirstangleEditFieldLabel.Visible = 'off';
            app.FirstangleEditFieldLabel.Text = 'First angle';
            app.FirstangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstangleEditField.Limits = [-360 360];
            app.FirstangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.FirstangleEditField.Tag = 'robotPara';
            app.FirstangleEditField.Editable = 'off';
            app.FirstangleEditField.Enable = 'off';
            app.FirstangleEditField.Visible = 'off';
            app.FixedfirstangleCheckBox = uicheckbox(app.UIFigure);
            app.FixedfirstangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.FixedfirstangleCheckBox.Tag = 'robotPara';
            app.FixedfirstangleCheckBox.Enable = 'off';
            app.FixedfirstangleCheckBox.Visible = 'off';
            app.FixedfirstangleCheckBox.Text = 'Fixed first angle';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.AnimateMenu = uimenu(app.ContextMenu);
            app.AnimateMenu.MenuSelectedFcn = createCallbackFcn(app, @AnimateMenuSelected, true);
            app.AnimateMenu.Text = 'Animate';
            % Assign app.ContextMenu
            app.OPTree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4_UI_Prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end